classdef NetworkGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GenerateNetworkButton           matlab.ui.control.Button
        AdvancedOptionsCheckBox         matlab.ui.control.CheckBox
        dr2EditField                    matlab.ui.control.NumericEditField
        dr2EditFieldLabel               matlab.ui.control.Label
        dr1EditField                    matlab.ui.control.NumericEditField
        dr1EditFieldLabel               matlab.ui.control.Label
        N2bondsEditField                matlab.ui.control.NumericEditField
        N2bondsEditFieldLabel           matlab.ui.control.Label
        PEditField                      matlab.ui.control.NumericEditField
        PEditFieldLabel                 matlab.ui.control.Label
        LongFirstCheckBox               matlab.ui.control.CheckBox
        DistributionHeightModeDropDown  matlab.ui.control.DropDown
        DistributionHeightModeDropDownLabel  matlab.ui.control.Label
        DistributionAssignementModeDropDown  matlab.ui.control.DropDown
        DistributionAssignementModeDropDownLabel  matlab.ui.control.Label
        BinWindowModeDropDown           matlab.ui.control.DropDown
        BinWindowModeDropDownLabel      matlab.ui.control.Label
        N2EditField                     matlab.ui.control.NumericEditField
        N2EditFieldLabel                matlab.ui.control.Label
        N1EditField                     matlab.ui.control.NumericEditField
        N1EditFieldLabel                matlab.ui.control.Label
        DistributionAssignmentModePolyDropDown  matlab.ui.control.DropDown
        DistributionAssignmentModePolyDropDownLabel  matlab.ui.control.Label
        SeedEditField                   matlab.ui.control.NumericEditField
        SeedEditFieldLabel              matlab.ui.control.Label
        ManualSeedCheckBox              matlab.ui.control.CheckBox
        LyEditField                     matlab.ui.control.NumericEditField
        LyEditFieldLabel                matlab.ui.control.Label
        LxEditField                     matlab.ui.control.NumericEditField
        LxEditFieldLabel                matlab.ui.control.Label
        KuhnlengthbEditField            matlab.ui.control.NumericEditField
        KuhnlengthbEditFieldLabel       matlab.ui.control.Label
        NumberofnetworksEditField       matlab.ui.control.NumericEditField
        NumberofnetworksEditFieldLabel  matlab.ui.control.Label
        DistributionTypeDropDown        matlab.ui.control.DropDown
        DistributionTypeDropDownLabel   matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        NetworkObject
    end
    
    methods (Access = private)
        
        function updatePlot(app)
            app.NetworkObject.dist_type = app.DistributionTypeDropDown.Value;
            app.NetworkObject.Nreplicates = app.NumberofnetworksEditField.Value;
            app.NetworkObject.b = app.KuhnlengthbEditField.Value;
            app.NetworkObject.Lx = app.LxEditField.Value;
            app.NetworkObject.Ly = app.LyEditField.Value;
            app.NetworkObject.imanualseed = app.ManualSeedCheckBox.Value;
            app.NetworkObject.seed = app.SeedEditField.Value;
            app.NetworkObject.distribution_assignment_mode_poly = app.DistributionAssignmentModePolyDropDown.Value;
            app.NetworkObject.N1 = app.N1EditField.Value;
            app.NetworkObject.N2 = app.N2EditField.Value;
            app.NetworkObject.bin_window_method = app.BinWindowModeDropDown.Value;
            app.NetworkObject.distribution_assignment_mode = app.DistributionAssignementModeDropDown.Value;
            app.NetworkObject.distribution_height_mode = app.DistributionHeightModeDropDown.Value;
            app.NetworkObject.long_first = app.LongFirstCheckBox.Value;
            app.NetworkObject.P = app.PEditField.Value;
            app.NetworkObject.N2_bonds = app.N2bondsEditField.Value;
            app.NetworkObject.dr1 = app.dr1EditField.Value;
            app.NetworkObject.dr2 = app.dr2EditField.Value;
            app.NetworkObject.iadvancedoptions = app.AdvancedOptionsCheckBox.Value;
            
            [Domain,Atoms,Bonds,Nvec] = generateNetwork(app.NetworkObject);
            
            % extract data from atoms
            x = Atoms(:,2);
            y = Atoms(:,3);
            Total_bond = length(Bonds);
            
            scatter(app.UIAxes,x,y, 6, 'k', 'filled')
            hold(app.UIAxes,'on')
            for ii = 1:Total_bond
                i1 = Bonds(ii,2);
                i2 = Bonds(ii,3);
                if Bonds(ii,5) == 1
                    plot(app.UIAxes,[Atoms(i1,2) Atoms(i2,2)], [Atoms(i1,3) Atoms(i2,3)], 'k-');
                else
                    plot(app.UIAxes,[Atoms(i1,2) Atoms(i2,2)], [Atoms(i1,3) Atoms(i2,3)], 'r-');
                end
            end
            hold(app.UIAxes,'off')
    
        end

        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.NetworkObject = network;
        end

        % Button pushed function: GenerateNetworkButton
        function GenerateNetworkButtonPushed(app, event)
            updatePlot(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DistributionTypeDropDownLabel = uilabel(app.UIFigure);
            app.DistributionTypeDropDownLabel.Text = 'Distribution Type';
            app.DistributionTypeDropDown = uidropdown(app.UIFigure);
            app.DistributionTypeDropDown.Items = {'polydisperse', 'bimodal'};
            app.DistributionTypeDropDown.Value = 'bimodal';
            app.NumberofnetworksEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofnetworksEditFieldLabel.Text = 'Number of networks';
            app.NumberofnetworksEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofnetworksEditField.Limits = [1 Inf];
            app.NumberofnetworksEditField.Value = 1;
            app.KuhnlengthbEditFieldLabel = uilabel(app.UIFigure);
            app.KuhnlengthbEditFieldLabel.Text = 'Kuhn length (b)';
            app.KuhnlengthbEditField = uieditfield(app.UIFigure, 'numeric');
            app.KuhnlengthbEditField.Limits = [0 Inf];
            app.KuhnlengthbEditField.Value = 1.6;
            app.LxEditFieldLabel = uilabel(app.UIFigure);
            app.LxEditFieldLabel.Text = 'Lx';
            app.LxEditField = uieditfield(app.UIFigure, 'numeric');
            app.LxEditField.Value = 100;
            app.LyEditFieldLabel = uilabel(app.UIFigure);
            app.LyEditFieldLabel.Text = 'Ly';
            app.LyEditField = uieditfield(app.UIFigure, 'numeric');
            app.LyEditField.Value = 100;
            app.ManualSeedCheckBox = uicheckbox(app.UIFigure);
            app.ManualSeedCheckBox.Text = 'Manual Seed';
            app.SeedEditFieldLabel = uilabel(app.UIFigure);
            app.SeedEditFieldLabel.Text = 'Seed';
            app.SeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistributionAssignmentModePolyDropDownLabel = uilabel(app.UIFigure);
            app.DistributionAssignmentModePolyDropDownLabel.Text = 'Distribution Assignment Mode Poly';
            app.DistributionAssignmentModePolyDropDown = uidropdown(app.UIFigure);
            app.DistributionAssignmentModePolyDropDown.Items = {'geom', 'range', 'pmf'};
            app.DistributionAssignmentModePolyDropDown.Value = 'geom';
            app.N1EditFieldLabel = uilabel(app.UIFigure);
            app.N1EditFieldLabel.Text = 'N1';
            app.N1EditField = uieditfield(app.UIFigure, 'numeric');
            app.N1EditField.Value = 20;
            app.N2EditFieldLabel = uilabel(app.UIFigure);
            app.N2EditFieldLabel.Text = 'N2';
            app.N2EditField = uieditfield(app.UIFigure, 'numeric');
            app.N2EditField.Value = 50;
            app.BinWindowModeDropDownLabel = uilabel(app.UIFigure);
            app.BinWindowModeDropDownLabel.Text = 'Bin Window Mode';
            app.BinWindowModeDropDown = uidropdown(app.UIFigure);
            app.BinWindowModeDropDown.Items = {'manual', 'adaptive'};
            app.BinWindowModeDropDown.Value = 'manual';
            app.DistributionAssignementModeDropDownLabel = uilabel(app.UIFigure);
            app.DistributionAssignementModeDropDownLabel.Text = 'Distribution Assignement Mode';
            app.DistributionAssignementModeDropDown = uidropdown(app.UIFigure);
            app.DistributionAssignementModeDropDown.Items = {'single', 'geom', 'gaussian'};
            app.DistributionAssignementModeDropDown.Value = 'gaussian';
            app.DistributionHeightModeDropDownLabel = uilabel(app.UIFigure);
            app.DistributionHeightModeDropDownLabel.Text = 'Distribution Height Mode';
            app.DistributionHeightModeDropDown = uidropdown(app.UIFigure);
            app.DistributionHeightModeDropDown.Items = {'prob', 'fixed'};
            app.DistributionHeightModeDropDown.Value = 'fixed';
            app.LongFirstCheckBox = uicheckbox(app.UIFigure);
            app.LongFirstCheckBox.Text = 'Long First';
            app.PEditFieldLabel = uilabel(app.UIFigure);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.UIFigure, 'numeric');
            app.N2bondsEditFieldLabel = uilabel(app.UIFigure);
            app.N2bondsEditFieldLabel.Text = 'N2 bonds';
            app.N2bondsEditField = uieditfield(app.UIFigure, 'numeric');
            app.dr1EditFieldLabel = uilabel(app.UIFigure);
            app.dr1EditFieldLabel.Text = 'dr1';
            app.dr1EditField = uieditfield(app.UIFigure, 'numeric');
            app.dr1EditField.Value = 10;
            app.dr2EditFieldLabel = uilabel(app.UIFigure);
            app.dr2EditFieldLabel.Text = 'dr2';
            app.dr2EditField = uieditfield(app.UIFigure, 'numeric');
            app.dr2EditField.Value = 10;
            app.AdvancedOptionsCheckBox = uicheckbox(app.UIFigure);
            app.AdvancedOptionsCheckBox.Text = 'Advanced Options';
            app.GenerateNetworkButton = uibutton(app.UIFigure, 'push');
            app.GenerateNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNetworkButtonPushed, true);
            app.GenerateNetworkButton.Text = 'Generate Network';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NetworkGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end